classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SequenceXYZEditField         matlab.ui.control.EditField
        SequenceXYZEditFieldLabel    matlab.ui.control.Label
        ComputeEulerButton           matlab.ui.control.Button
        Axis3DropDown                matlab.ui.control.DropDown
        Axis3DropDownLabel           matlab.ui.control.Label
        Axis2DropDown                matlab.ui.control.DropDown
        Axis2DropDownLabel           matlab.ui.control.Label
        Axis1DropDown                matlab.ui.control.DropDown
        Axis1DropDownLabel           matlab.ui.control.Label
        GlobalDropDown               matlab.ui.control.DropDown
        GlobalDropDownLabel          matlab.ui.control.Label
        RelativeDropDown             matlab.ui.control.DropDown
        RelativeDropDownLabel        matlab.ui.control.Label
        CalculatespecificEuleranglesLabel  matlab.ui.control.Label
        BallreleasemomentframeLabel  matlab.ui.control.Label
        BRFrameField                 matlab.ui.control.NumericEditField
        FootcontactframeLabel        matlab.ui.control.Label
        FCFrameField                 matlab.ui.control.NumericEditField
        SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown  matlab.ui.control.DropDown
        SelectEulerfromprojectsegmentrelativetoreferenceDropDownLabel  matlab.ui.control.Label
        SelectasegmentDropDown       matlab.ui.control.DropDown
        SelectasegmentDropDownLabel  matlab.ui.control.Label
        EulerAxes                    matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    % Filtered markerpoints
    FilteredData        table

    % Local coordinate axes (N×3×3)
    F  double
    U  double
    T  double
    P  double
    TL double
    SL double

    % Relative rotation matrices
    R_rel_UT   double
    R_rel_FU   double
    R_rel_TP   double
    R_rel_STL  double

    % Euler‐angles (N×3)
    Euler_shoulder  double
    Euler_elbow     double
    Euler_core      double
    Euler_pelvis    double
    Euler_knee      double
    Euler_thorax    double

    % Amount of frames
    nFrames        double

    % Event‐indices
    FC_index       double
    BR_index       double
    BR_time        double
    end
    
    
    methods (Access = private)
        
        function updatePlot(app)
            % 1) Make UIAxes proper
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            axis(app.UIAxes,'equal')
            grid(app.UIAxes,'on')
            xlabel(app.UIAxes,'X (mm)')
            ylabel(app.UIAxes,'Y (mm)')
            zlabel(app.UIAxes,'Z (mm)')
            title(app.UIAxes,'3D‐axes')

            % 2) Read chosen segment
            seg = lower(app.SelectasegmentDropDown.Value);

            % 3) Plot local axes every 10 frames
            for f = 1:10:app.nFrames
                switch seg
                    case 'upperarm'
                        M = [app.FilteredData.ARX,app.FilteredData.ARY,app.FilteredData.ARZ];
                        origin = M(f,:);
                        Rm = squeeze(app.U(f,:,:));
                    case 'forearm'
                        M = [app.FilteredData.PMRX,app.FilteredData.PMRY,app.FilteredData.PMRZ];
                        origin = M(f,:);
                        Rm = squeeze(app.F(f,:,:));
                    case 'thorax'
                        M = [app.FilteredData.MSX,app.FilteredData.MSY,app.FilteredData.MSZ];
                        origin = M(f,:);
                        Rm = squeeze(app.T(f,:,:));
                    case 'pelvis'
                        M = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ];
                        origin = M(f,:);
                        Rm = squeeze(app.P(f,:,:));
                    case 'left thigh'
                        M = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ];
                        origin = M(f,:);
                        Rm = squeeze(app.TL(f,:,:));
                    case 'left shank'
                        M1 = [app.FilteredData.MLLX,app.FilteredData.MLLY,app.FilteredData.MLLZ];
                        M2 = [app.FilteredData.MMLX,app.FilteredData.MMLY,app.FilteredData.MMLZ];
                        origin = 0.5*(M1(f,:)+M2(f,:));
                        Rm = squeeze(app.SL(f,:,:));
                    otherwise
                        origin = [0 0 0];
                        Rm = eye(3);
                end
                % plot the 3 axes
                % X‐axes (rood)
                quiver3(app.UIAxes, origin(1), origin(2), origin(3), 50*Rm(1,1), 50*Rm(2,1), 50*Rm(3,1), 'r','MaxHeadSize',0.3);

                % Y‐axes (groen)
                quiver3(app.UIAxes, origin(1), origin(2), origin(3), 50*Rm(1,2), 50*Rm(2,2), 50*Rm(3,2), 'g','MaxHeadSize',0.3);

                % Z‐axes (blauw)
                quiver3(app.UIAxes, origin(1), origin(2), origin(3), 50*Rm(1,3), 50*Rm(2,3), 50*Rm(3,3), 'b','MaxHeadSize',0.3);

            end
            legend(app.UIAxes,{'X','Y','Z'},'Location','northeast')

            % 4) Mark Foot Contact (only Left shank)
            if strcmp(seg,'Left shank')
                M1 = [app.FilteredData.MLLX,app.FilteredData.MLLY,app.FilteredData.MLLZ];
                M2 = [app.FilteredData.MMLX,app.FilteredData.MMLY,app.FilteredData.MMLZ];
                pt = 0.5*(M1(app.FC_index,:)+M2(app.FC_index,:));
                scatter3(app.UIAxes,pt(1),pt(2),pt(3),80,'m','filled')
            end

            % 5) Mark Ball Release (only Forearm)
            if strcmp(seg,'Forearm')
                PLR = [app.FilteredData.PLRX,app.FilteredData.PLRY,app.FilteredData.PLRZ];
                pt = PLR(app.BR_index,:);
                scatter3(app.UIAxes,pt(1),pt(2),pt(3),80,'c','filled')
            end

            hold(app.UIAxes,'off')

            % 6) Plot de Euler hoeken in EulerAxes
            cla(app.EulerAxes)
            hold(app.EulerAxes,'on')
            seq = app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.Value;      % of jouw dropdown-handle
            switch seq
                case 'Shoulder: UpperArm relative to Thorax'
                    angles = app.Euler_shoulder;  
                    labels = {'γ','β','α'};
                case 'Elbow:   Forearm relative to UpperArm'
                    angles = app.Euler_elbow;
                    labels = {'γ','β','α'};
                case 'Core:    Thorax relative to Pelvis'
                    angles = app.Euler_core;
                    labels = {'ϕ','θ','ψ'};
                case 'Pelvis:  Pelvis in global frame'
                    angles = app.Euler_pelvis;
                    labels = {'ϕ','θ','ψ'};
                case 'Thorax:   Thorax in global frame'
                    angles = app.Euler_thorax;
                    labels = {'X','Y','Z'};
                case 'Knee:     Shank relative to Thigh'
                    angles = app.Euler_knee;
                    labels = {'X','Y','Z'};
                otherwise
                    angles = zeros(app.nFrames,3);
                    labels = {'a','b','c'};
            end
            % unwrapped indien gewenst (neem je unwrapEulerAngles functie)
            angles = unwrapEulerAngles(angles);
        
            % plot alle drie curves
            x = (1:app.nFrames)';
            plot(app.EulerAxes, x, angles(:,1), 'r-', 'LineWidth',1.2)
            plot(app.EulerAxes, x, angles(:,2), 'g-', 'LineWidth',1.2)
            plot(app.EulerAxes, x, angles(:,3), 'b-', 'LineWidth',1.2)
            xlabel(app.EulerAxes,'Frame index')
            ylabel(app.EulerAxes,'Angle (°)')
            title(app.EulerAxes,'Euler angles over time')
            legend(app.EulerAxes, labels, 'Location','best')
            hold(app.EulerAxes,'off')

        end
        
        function axis = getAxisFromLabel(app, label, i)
    if startsWith(label, 'GLOBAL')
        switch label
            case 'GLOBAL1'
                axis = [1; 0; 0];
            case 'GLOBAL2'
                axis = [0; 1; 0];
            case 'GLOBAL3'
                axis = [0; 0; 1];
        end
    else
        % Extract letter group and axis type
        axisChar = label(1);       % 'X', 'Y' of 'Z'
        frameCode = extractAfter(label, 1);  % bv. 'T', 'U', 'TL', 'SL'

        % Map frame code to de juiste matrix
        switch frameCode
            case 'U'
                frame = app.U;
            case 'F'
                frame = app.F;
            case 'T'
                frame = app.T;
            case 'P'
                frame = app.P;
            case 'TL'
                frame = app.TL;
            case 'SL'
                frame = app.SL;
            otherwise
                axis = [1; 0; 0];  % fallback
                return
        end

        % Map X/Y/Z naar kolomindex
        switch axisChar
            case 'X'
                col = 1;
            case 'Y'
                col = 2;
            case 'Z'
                col = 3;
        end

        axis = squeeze(frame(i,:,col))';
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1) Load & filter
            filename = '10Ax1.tsv'; fc=10; fs=300;
            app.FilteredData = loadAndFilterTSV(filename,fc,fs);

            % 1.1) bepaal hier al het aantal frames:
            app.nFrames = height(app.FilteredData);

            % 2) Local frames
            [app.F,app.U,app.T,app.P,app.TL,app.SL] = computeLocalFrames(app.FilteredData);

            % 2.1) Continuity‐correctie voor alle assenstelsels
             app.F  = fixAttitudeContinuity(app.F);
             app.U  = fixAttitudeContinuity(app.U);
             app.T  = fixAttitudeContinuity(app.T);
             app.P  = fixAttitudeContinuity(app.P);
             app.TL = fixAttitudeContinuity(app.TL);
             app.SL = fixAttitudeContinuity(app.SL);

            % 3) Relative rotations
            [app.R_rel_UT,app.R_rel_FU,app.R_rel_TP,app.R_rel_STL] = computeRelativeRotations(app.U,app.F,app.T,app.P,app.TL,app.SL);

            % 4) Euler-angles calculates
            [app.Euler_shoulder, app.Euler_elbow, app.Euler_core, app.Euler_pelvis,app.Euler_thorax, app.Euler_knee] = computeEulerAngles(app.nFrames, app.U, app.T, app.P, app.TL, app.SL, app.F);

            % 4.1) Hoeken unwrappen
            app.Euler_shoulder = unwrapEulerAngles(app.Euler_shoulder);
            app.Euler_elbow    = unwrapEulerAngles(app.Euler_elbow);
            app.Euler_core     = unwrapEulerAngles(app.Euler_core);
            app.Euler_pelvis   = unwrapEulerAngles(app.Euler_pelvis);
            app.Euler_thorax   = unwrapEulerAngles(app.Euler_thorax);
            app.Euler_knee     = unwrapEulerAngles(app.Euler_knee);

            % 5) Detect events (foot contact, ball release)
            app.nFrames = height(app.FilteredData);
            window = 410:470;
            app.FC_index = computeFootContactLeftLeg(app.FilteredData,window,fs);
            [app.BR_index,app.BR_time] = computeBallRelease(app.FilteredData,app.FC_index,fs);

            % 6) Show the events direct in the field
            app.FCFrameField.Value = app.FC_index;
            app.BRFrameField.Value = app.BR_index;

            % 7) First 3D-plot
            app.updatePlot();

            % 8) set default values for Euler angles
            app.SequenceXYZEditField.Value = 'XYZ';
            app.RelativeDropDown.Value = 'None';
            app.GlobalDropDown.Value = 'None';
            app.Axis1DropDown.Value = 'GLOBAL1';
            app.Axis2DropDown.Value = 'GLOBAL2';
            app.Axis3DropDown.Value = 'GLOBAL3';

        end

        % Value changed function: SelectasegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            %value = app.SelectasegmentDropDown.Value;
            app.updatePlot;
        end

        % Value changed function: 
        % SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown
        function EulerSequenceDropDownValueChanged(app, event)
            %value = app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.Value;
            app.updatePlot;
        end

        % Button pushed function: ComputeEulerButton
        function ComputeEulerButtonPushed(app, event)
            relVal = app.RelativeDropDown.Value;
            globVal = app.GlobalDropDown.Value;
            axis1Str = app.Axis1DropDown.Value;
            axis2Str = app.Axis2DropDown.Value;
            axis3Str = app.Axis3DropDown.Value;
            useGlobal = false;

            % Use computeEulerFromAxes for other choices with a rotation
            % matrix
            switch relVal
                case 'Upperarm_Thorax'
                    R = app.R_rel_UT;
                case 'Forearm_Upperarm'
                    R = app.R_rel_FU;
                case 'Thorax_Pelvis'
                    R = app.R_rel_TP;
                case 'Shank Left_Thigh Left'
                    R = app.R_rel_STL;
                case 'None'
                    useGlobal = true;
                    % Use global segment matrix
                    switch globVal
                        case 'Thorax'
                            R = app.T;
                        case 'Pelvis'
                            R = app.P;
                        case 'Upper arm'
                            R = app.U;
                        case 'Forearm'
                            R = app.F;
                        case 'Thigh Left'
                            R = app.TL;
                        case 'Shank Left'
                            R = app.SL;
                    end
            end


            % Compute Euler angles
            angles = zeros(app.nFrames, 3);
            if useGlobal 
                for i = 1:app.nFrames
                    Rf = squeeze(R(i,:,:));
                    seq = upper(app.SequenceXYZEditField.Value); % User-defined sequence like 'ZYX'
                    try
                        angles(i,:) = rad2deg(rotm2eul(Rf, seq));
                    catch
                        uialert(app.UIFigure, 'Invalid Euler sequence. Use combinations like XYZ, ZYX, etc.', 'Invalid Input');
                        return
                    end
                end
            else    
                for i = 1:app.nFrames
                    Rf = squeeze(R(i,:,:));
                    % Extract axis vectors
                    axis1 = app.getAxisFromLabel(axis1Str, i);
                    axis2 = app.getAxisFromLabel(axis2Str, i);
                    axis3 = app.getAxisFromLabel(axis3Str, i);
                    angles(i,:) = computeEulerFromAxes(Rf, axis1, axis2, axis3);
                end
            end
            angles = unwrapEulerAngles(angles);

            % Plot result
            cla(app.EulerAxes)
            hold(app.EulerAxes,'on')
            plot(app.EulerAxes, 1:app.nFrames, angles(:,1), 'r', 'LineWidth', 1.2)
            plot(app.EulerAxes, 1:app.nFrames, angles(:,2), 'g', 'LineWidth', 1.2)
            plot(app.EulerAxes, 1:app.nFrames, angles(:,3), 'b', 'LineWidth', 1.2)
            legend(app.EulerAxes, {'angle1','angle2','angle3'})
            xlabel(app.EulerAxes, 'Frame index')
            ylabel(app.EulerAxes, 'Angle (°)')
            title(app.EulerAxes, 'Custom Euler angles')
            hold(app.EulerAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D-axes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EulerAxes = uiaxes(app.UIFigure);
            title(app.EulerAxes, 'Euler angles')
            xlabel(app.EulerAxes, 'Frame index')
            ylabel(app.EulerAxes, 'Angle (°)')
            zlabel(app.EulerAxes, 'Z')
            app.SelectasegmentDropDownLabel = uilabel(app.UIFigure);
            app.SelectasegmentDropDownLabel.Text = 'Select a segment:';
            app.SelectasegmentDropDown = uidropdown(app.UIFigure);
            app.SelectasegmentDropDown.Items = {'Upperarm', 'Forearm', 'Thorax', 'Pelvis', 'Left thigh', 'Left shank', ''};
            app.SelectasegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SelectasegmentDropDown.Tag = 'SegmentDropDown';
            app.SelectasegmentDropDown.Value = 'Upperarm';
            app.SelectEulerfromprojectsegmentrelativetoreferenceDropDownLabel = uilabel(app.UIFigure);
            app.SelectEulerfromprojectsegmentrelativetoreferenceDropDownLabel.Text = 'Or select premade Euler from project (segment relative to reference):';
            app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown = uidropdown(app.UIFigure);
            app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.Items = {'Shoulder: UpperArm relative to Thorax', 'Elbow:   Forearm relative to UpperArm', 'Core:    Thorax relative to Pelvis', 'Pelvis:  Pelvis in global frame', 'Thorax:   Thorax in global frame', 'Knee:     Shank relative to Thigh'};
            app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @EulerSequenceDropDownValueChanged, true);
            app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.Tag = 'EulerSequenceDropDown';
            app.SelectpremadeEulerfromprojectsegmentrelativetoreferenceDropDown.Value = 'Shoulder: UpperArm relative to Thorax';
            app.FCFrameField = uieditfield(app.UIFigure, 'numeric');
            app.FootcontactframeLabel = uilabel(app.UIFigure);
            app.FootcontactframeLabel.Text = 'Foot contact frame:';
            app.BRFrameField = uieditfield(app.UIFigure, 'numeric');
            app.BallreleasemomentframeLabel = uilabel(app.UIFigure);
            app.BallreleasemomentframeLabel.Text = 'Ball release moment frame:';
            app.CalculatespecificEuleranglesLabel = uilabel(app.UIFigure);
            app.CalculatespecificEuleranglesLabel.Text = ' Calculate specific Euler angles';
            app.RelativeDropDownLabel = uilabel(app.UIFigure);
            app.RelativeDropDownLabel.Text = 'Relative';
            app.RelativeDropDown = uidropdown(app.UIFigure);
            app.RelativeDropDown.Items = {'None', 'Upperarm_Thorax', 'Forearm_Upperarm', 'Thorax_Pelvis', 'Shank Left_Thigh Left'};
            app.RelativeDropDown.Value = 'Upperarm_Thorax';
            app.GlobalDropDownLabel = uilabel(app.UIFigure);
            app.GlobalDropDownLabel.Text = 'Global';
            app.GlobalDropDown = uidropdown(app.UIFigure);
            app.GlobalDropDown.Items = {'None', 'Upper arm', 'Forearm', 'Pelvis', 'Thorax', 'Thigh Left', 'Shank Left'};
            app.GlobalDropDown.Value = 'Upper arm';
            app.Axis1DropDownLabel = uilabel(app.UIFigure);
            app.Axis1DropDownLabel.Text = 'Axis1';
            app.Axis1DropDown = uidropdown(app.UIFigure);
            app.Axis1DropDown.Items = {'GLOBAL1', 'XU', 'YU', 'ZU', 'XF', 'YF', 'ZF', 'XT', 'YT', 'ZT', 'XP', 'YP', 'ZP', 'XTL', 'YTL', 'ZTL', 'XSL', 'YSL', 'ZSL'};
            app.Axis1DropDown.Value = 'XU';
            app.Axis2DropDownLabel = uilabel(app.UIFigure);
            app.Axis2DropDownLabel.Text = 'Axis2';
            app.Axis2DropDown = uidropdown(app.UIFigure);
            app.Axis2DropDown.Items = {'GLOBAL2', 'XU', 'YU', 'ZU', 'XF', 'YF', 'ZF', 'XT', 'YT', 'ZT', 'XP', 'YP', 'ZP', 'XTL', 'YTL', 'ZTL', 'XSL', 'YSL', 'ZSL'};
            app.Axis2DropDown.Value = 'XU';
            app.Axis3DropDownLabel = uilabel(app.UIFigure);
            app.Axis3DropDownLabel.Text = 'Axis3';
            app.Axis3DropDown = uidropdown(app.UIFigure);
            app.Axis3DropDown.Items = {'GLOBAL3', 'XU', 'YU', 'ZU', 'XF', 'YF', 'ZF', 'XT', 'YT', 'ZT', 'XP', 'YP', 'ZP', 'XTL', 'YTL', 'ZTL', 'XSL', 'YSL', 'ZSL'};
            app.Axis3DropDown.Value = 'XU';
            app.ComputeEulerButton = uibutton(app.UIFigure, 'push');
            app.ComputeEulerButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeEulerButtonPushed, true);
            app.ComputeEulerButton.Text = 'ComputeEuler';
            app.SequenceXYZEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceXYZEditFieldLabel.Text = 'Sequence (XYZ, ...';
            app.SequenceXYZEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end