classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EulervolgordeLabel          matlab.ui.control.Label
        EulerSequenceDropDown       matlab.ui.control.DropDown
        EulerSequenceDropDownLabel  matlab.ui.control.Label
        FrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel         matlab.ui.control.Label
        FrameindexLabel             matlab.ui.control.Label
        KiessegmentLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        FrameSliderLabel            matlab.ui.control.Label
        SegmentDropDown             matlab.ui.control.DropDown
        SegmentDropDownLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Gevilterde marker‐tabel (zoals loadAndFilterTSV)
    FilteredData  

    % Lokale asstelsels (N×3×3 arrays)
    F  
    U  
    T  
    P  
    TL  
    SL  

    % Relatieve rotatiematrices (N×3×3 arrays)
    R_rel_UT  
    R_rel_FU  
    R_rel_TP  
    R_rel_STL  

    % Euler‐hoeken (N×3)
    Euler_shoulder  
    Euler_elbow  
    Euler_core  
    Euler_pelvis  
    Euler_knee  

    % Aantal frames (hoogte FilteredData)
    nFrames  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ezd)
        % 1) Laad en filter de TSV (gebruik dezelfde filename als in main.m)
        %    (Zet hier de ’10Ax1.tsv’ in dezelfde folder als je .mlapp, of geef volledig pad.)
        filename = '10Ax1.tsv';
        fc = 10;
        fs = 300;
        app.FilteredData = loadAndFilterTSV(filename, fc, fs);

        % 2) Bereken lokale assenstelsels
        [app.F, app.U, app.T, app.P, app.TL, app.SL] = computeLocalFrames(app.FilteredData);

        % 3) Bereken relatieve rotatiematrices
        [app.R_rel_UT, app.R_rel_FU, app.R_rel_TP, app.R_rel_STL] = computeRelativeRotations(app.U, app.F, app.T, app.P, app.TL, app.SL);

        % 4) Bereken Euler‐hoeken (optioneel; gebruiken we nu niet in de plot,
        %    maar wel als je later Euler‐waarden wil laten zien)
        [app.Euler_shoulder, app.Euler_elbow, app.Euler_core, app.Euler_pelvis,  app.Euler_knee] = computeEulerAngles(app.R_rel_UT, app.R_rel_FU, app.R_rel_TP, app.R_rel_STL, app.U, app.T, app.P, app.TL, app.F);

        % 5) Bepaal aantal frames uit de hoogte van de gefilterde tabel
        app.nFrames = height(app.FilteredData);

        % 6) Configureer slider en editfield
        app.FrameSlider.Limits    = [1, app.nFrames];
        app.FrameSlider.MajorTicks = [];    % je kunt ticks weglaten of opwaarden
        app.FrameSlider.Value     = 1;      % start op frame 1
        app.FrameEditField.Limits = [1, app.nFrames];
        app.FrameEditField.Value  = 1;

        % 7) Teken de beginplot (segment = default “Upperarm”, frame = 1)
        app.updatePlot;

        end

        function updatePlot(app)
        % 1) Lees welk segment de gebruiker koos
        selectedSegment = app.SegmentDropDown.Value;  % bijv. 'Upperarm'

        % 2) Lees welk frame‐nummer in de slider/editfield staat
        %    (kan niet tegelijk verschillend zijn, we synchroniseren die verderop)
        frameIdx = round(app.FrameSlider.Value);

        % 3) Haal origin en assen‐matrix op volgens selectie:
        %    We gaan per segment een 3×3‐matrix en een “origin” vector (3×1) definiëren.
        switch lower(selectedSegment)
            case 'upperarm'
                % Upper Arm: origin = AR‐marker uit filtered_data
                AR_data = [app.FilteredData.ARX,app.FilteredData.ARY,app.FilteredData.ARZ];
                origin = AR_data(frameIdx, :);
                Rmat = squeeze(app.U(frameIdx, :, :));  

            case 'forearm'
                % Forearm: origin = PMR‐marker
                PMR_data = [app.FilteredData.PMRX, app.FilteredData.PMRY, app.FilteredData.PMRZ];
                origin = PMR_data(frameIdx, :);
                Rmat = squeeze(app.F(frameIdx, :, :));

            case 'thorax'
                % Thorax: origin = MS‐marker
                MS_data = [app.FilteredData.MSX,app.FilteredData.MSY,app.FilteredData.MSZ];
                origin = MS_data(frameIdx, :);
                Rmat = squeeze(app.T(frameIdx, :, :));

            case 'pelvis'
                % Pelvis: origin = HL (hip left)
                HL_data = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ];
                origin = HL_data(frameIdx, :);
                Rmat = squeeze(app.P(frameIdx, :, :));

            case 'left thigh'
                % Left Thigh: origin = HL (zelfde heuppunt)
                HL_data = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ];
                origin = HL_data(frameIdx, :);
                Rmat = squeeze(app.TL(frameIdx, :, :));

            case 'left shank'
                % Left Shank: origin = (MLL+MML)/2
                MLL_data = [app.FilteredData.MLLX,app.FilteredData.MLLY,app.FilteredData.MLLZ];
                MML_data = [app.FilteredData.MMLX,app.FilteredData.MMLY,app.FilteredData.MMLZ];
                origin = 0.5 * (MLL_data(frameIdx, :) + MML_data(frameIdx, :));
                Rmat = squeeze(app.SL(frameIdx, :, :));

            otherwise
                % Als (per ongeluk) iets anders staat, default op oorsprong [0 0 0]
                origin = [0, 0, 0];
                Rmat   = eye(3);
        end

        % 4) Maak UIAxes leeg en plot de drie assen als quiver3
        cla(app.UIAxes)             % wis alles op de assen
        hold(app.UIAxes, 'on')
        axis(app.UIAxes, 'equal')
        xlabel(app.UIAxes, 'X (mm)')
        ylabel(app.UIAxes, 'Y (mm)')
        zlabel(app.UIAxes, 'Z (mm)')
        grid(app.UIAxes, 'on')
        title(app.UIAxes, sprintf('%s  – Frame %d', selectedSegment, frameIdx))

        % 5) Pak de kolommen van Rmat: 1e kolom = X-as, 2e kolom = Y-as, 3e kolom = Z-as
        Xdir = Rmat(:,1);
        Ydir = Rmat(:,2);
        Zdir = Rmat(:,3);

        % 6) Stel een schaalfactor in (zodat de pijlen niet té klein/groot zijn)
        scale = 50;  % experimenteer met bv. 30, 50, 100, naar wens

        % 7) Plot quivers vanuit ’origin’
        quiver3(app.UIAxes, origin(1), origin(2), origin(3),scale*Xdir(1), scale*Xdir(2), scale*Xdir(3),'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
        quiver3(app.UIAxes, origin(1), origin(2), origin(3), scale*Ydir(1), scale*Ydir(2), scale*Ydir(3),'g', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
        quiver3(app.UIAxes, origin(1), origin(2), origin(3), scale*Zdir(1), scale*Zdir(2), scale*Zdir(3),'b', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);

        % 8) Legende: de drie kleuren
        legend(app.UIAxes, {'X-as', 'Y-as', 'Z-as'}, 'Location', 'northeast');

        % === NU DE EULER‐HOEKEN BEREKENEN EN IN DE TITEL ZETTEN ===

        % 9) Haal de gekozen Euler‐volgorde op uit de dropdown
        seq = app.EulerSequenceDropDown.Value;  

        % 10) Bepaal welke relatieve rotatiematrix we moeten gebruiken,
        %     en welke rotm2eul‐asvolgorde erbij hoort:
        switch seq
            case 'Y-X-Y (ISB shoulder)'
                % Schouder: gebruik R_rel_UT = Thorax' * UpperArm
                R_e = squeeze(app.R_rel_UT(frameIdx,:,:));  
                angles_rad = rotm2eul(R_e, 'YXY');   % extrinsiek Y‐X‐Y
    
                % Zet de hoeken in graden en formatteer in tekst
                eulerText = sprintf(' γ=%.1f°, β=%.1f°, α=%.1f°', ...
                            rad2deg(angles_rad(1)), ...
                            rad2deg(angles_rad(2)), ...
                            rad2deg(angles_rad(3)));
    
            case 'Z-X-Y (ISB elbow)'
                % Elleboog: gebruik R_rel_FU = UpperArm' * Forearm
                R_e = squeeze(app.R_rel_FU(frameIdx,:,:));  
                angles_rad = rotm2eul(R_e, 'ZXY');   % extrinsiek Z‐X‐Y
    
                eulerText = sprintf(' γ=%.1f°, β=%.1f°, α=%.1f°', ...
                            rad2deg(angles_rad(1)), ...
                            rad2deg(angles_rad(2)), ...
                            rad2deg(angles_rad(3)));
    
            case 'X-Y-Z'
                % Voorbeeld: “core” (Thorax t.o.v. Pelvis)
                R_e = squeeze(app.R_rel_TP(frameIdx,:,:));  
                angles_rad = rotm2eul(R_e, 'XYZ');   % extrinsiek X‐Y‐Z
    
                eulerText = sprintf(' ϕ=%.1f°, θ=%.1f°, ψ=%.1f°', ...
                            rad2deg(angles_rad(1)), ...
                            rad2deg(angles_rad(2)), ...
                            rad2deg(angles_rad(3)));
    
            case 'Y-Z-X'
                % Nog een voorbeeldvolgorde (ook op R_rel_TP)
                R_e = squeeze(app.R_rel_TP(frameIdx,:,:));  
                angles_rad = rotm2eul(R_e, 'YZX');   % extrinsiek Y‐Z‐X
    
                eulerText = sprintf(' ϕ=%.1f°, θ=%.1f°, ψ=%.1f°', ...
                            rad2deg(angles_rad(1)), ...
                            rad2deg(angles_rad(2)), ...
                            rad2deg(angles_rad(3)));
    
            otherwise
                % Als er onverwacht iets anders in staat
                eulerText = '';
        end
    
        % 11) Zet de titel inclusief segmentnaam, framenummer en Euler‐tekst
        title(app.UIAxes, sprintf('%s  – Frame %d  %s', selectedSegment, frameIdx, eulerText));
    
        hold(app.UIAxes, 'off');
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
        % 1) Lees het nieuwe slider‐waarde (kan non‐integer zijn → afronden)
        newFrame = round(app.FrameSlider.Value);

        % 2) Zet de editfield gelijk aan die ronde waarde
        app.FrameEditField.Value = newFrame;

        % 3) Update de plot
        app.updatePlot;
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
        % 1) Lees het nieuwe getal in de editfield
        newFrame = round(app.FrameEditField.Value);
    
        % 2) Beveilig dat newFrame niet onder 1 of boven nFrames valt
        newFrame = max(1, min(app.nFrames, newFrame));

        % 3) Zet de slider‐waarde op dezelfde frame (slider accepteert reëel getal,
        %    dus als we een integer binnenzetten, zit deze daar exact)
        app.FrameSlider.Value = newFrame;

        % 4) Update de plot
        app.updatePlot;
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
        app.updatePlot;
        end

        % Value changed function: EulerSequenceDropDown
        function EulerSequenceDropDownValueChanged(app, event)
            app.updatePlot;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axes3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SegmentDropDownLabel = uilabel(app.UIFigure);
            app.SegmentDropDownLabel.Text = 'Segment Drop Down';
            app.SegmentDropDown = uidropdown(app.UIFigure);
            app.SegmentDropDown.Items = {'Upperarm', 'Forearm', 'Thorax', 'Pelvis', 'Left thigh', 'Left shank'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = 'Upperarm';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame-Slider';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.KiessegmentLabel = uilabel(app.UIFigure);
            app.KiessegmentLabel.Text = 'Kies segment:';
            app.FrameindexLabel = uilabel(app.UIFigure);
            app.FrameindexLabel.Text = 'Frame-index';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = ' Frame Edit Field';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.Limits = [1 100];
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.EulerSequenceDropDownLabel = uilabel(app.UIFigure);
            app.EulerSequenceDropDownLabel.Text = 'EulerSequenceDropDown';
            app.EulerSequenceDropDown = uidropdown(app.UIFigure);
            app.EulerSequenceDropDown.Items = {'Y-X-Y (ISB shoulder)', 'Z-X-Y (ISB elbow)', 'X-Y-Z ', 'Y-Z-X'};
            app.EulerSequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @EulerSequenceDropDownValueChanged, true);
            app.EulerSequenceDropDown.Value = 'Y-X-Y (ISB shoulder)';
            app.EulervolgordeLabel = uilabel(app.UIFigure);
            app.EulervolgordeLabel.Text = 'Euler-volgorde';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end